classdef VL_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CoordinateSelectionTab        matlab.ui.container.Tab
        PreviewButton                 matlab.ui.control.Button
        DiscHeightmmEditField         matlab.ui.control.NumericEditField
        DiscHeightmmEditFieldLabel    matlab.ui.control.Label
        InnerDiscMLLengthmmEditField  matlab.ui.control.NumericEditField
        InnerDiscMLLengthmmEditFieldLabel  matlab.ui.control.Label
        OuterDiscMLLengthmmEditField  matlab.ui.control.NumericEditField
        OuterDiscMLLengthmmEditFieldLabel  matlab.ui.control.Label
        InnerDiscAPlengthmmEditField  matlab.ui.control.NumericEditField
        InnerDiscAPlengthmmEditFieldLabel  matlab.ui.control.Label
        OuterDiscAPLengthmmEditField  matlab.ui.control.NumericEditField
        OuterDiscAPLengthmmEditFieldLabel  matlab.ui.control.Label
        AngleofLigamentsdegEditField  matlab.ui.control.NumericEditField
        AngleofLigamentsdegEditFieldLabel  matlab.ui.control.Label
        NumberofLigamentsEditField    matlab.ui.control.NumericEditField
        NumberofLigamentsEditFieldLabel  matlab.ui.control.Label
        CoordinateSelectionLabel      matlab.ui.control.Label
        UIAxes3D                      matlab.ui.control.UIAxes
        Tab2                          matlab.ui.container.Tab
    end

    
    properties (Access = private)
        NumLigs % User inputted number of ligaments in arrangement 4-100
        AngleLigs % User inputted angle of ligaments in deg (to horizontal)
        OuterAPLength % User inputted Outer AP length of disc in mm (from CT or Lit)
        InnerAPLength % User inputted Inner AP length of disc in mm (from CT or Lit)
        OuterMLLength % User inputted Outer ML length of disc in mm (from CT or Lit)
        InnerMLLength % User inputted Inner ML length of disc in mm (from CT or Lit)
        DiscHeight % User inputted height of disc in mm (from CT or Lit)
        ProxCoords % Coordinates associated with the proximal vertebral body
        DistCoords % Coordinates associated with the distal vertebral body
        LamenaThickness % Given from lit? 
    end
    
    methods (Access = private)
        
   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            view(app.UIAxes3D, 3);   % Default 3D view
            xlabel(app.UIAxes3D, 'X');
            ylabel(app.UIAxes3D, 'Y');
            zlabel(app.UIAxes3D, 'Z');
        end

        % Value changed function: NumberofLigamentsEditField
        function NumberofLigamentsEditFieldValueChanged(app, event)
            app.NumLigs = app.NumberofLigamentsEditField.Value;
            
        end

        % Value changed function: AngleofLigamentsdegEditField
        function AngleofLigamentsdegEditFieldValueChanged(app, event)
            app.AngleLigs = app.AngleofLigamentsdegEditField.Value;
            
        end

        % Value changed function: OuterDiscAPLengthmmEditField
        function OuterDiscAPLengthmmEditFieldValueChanged(app, event)
         app.OuterAPLength = app.OuterDiscAPLengthmmEditField.Value;
            
        end

        % Value changed function: InnerDiscAPlengthmmEditField
        function InnerDiscAPlengthmmEditFieldValueChanged(app, event)
            app.InnerAPLength = app.InnerDiscAPlengthmmEditField.Value;
            
        end

        % Value changed function: OuterDiscMLLengthmmEditField
        function OuterDiscMLLengthmmEditFieldValueChanged(app, event)
            app.OuterMLLength = app.OuterDiscMLLengthmmEditField.Value;
            
        end

        % Value changed function: InnerDiscMLLengthmmEditField
        function InnerDiscMLLengthmmEditFieldValueChanged(app, event)
            app.InnerMLLength = app.InnerDiscMLLengthmmEditField.Value;
            
        end

        % Value changed function: DiscHeightmmEditField
        function DiscHeightmmEditFieldValueChanged(app, event)
            app.DiscHeight = app.DiscHeightmmEditField.Value;
            
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)

            app.LamenaThickness = 0.55;
 
            [app.ProxCoords, app.DistCoords] = GetLigCoordinates(app.OuterAPLength, ...
                app.OuterMLLength,app.InnerAPLength, app.InnerMLLength, app.DiscHeight, ...
                app.LamenaThickness,app.NumLigs, app.AngleLigs);
            %Vertebra mock up in plot 
            theta = linspace(0, 2*pi, 200);
            a = app.OuterAPLength;
            b = app.OuterMLLength;
            phi = pi/2;
            
            % Ellipse equation in XY plane
            x = a*cos(theta)*cos(phi) - b*sin(theta)*sin(phi);
            y = a*cos(theta)*sin(phi) + b*sin(theta)*cos(phi);
            z_sup = app.DiscHeight/2 * ones(size(theta));   % Flat at z0
            z_inf = app.DiscHeight/2 * ones(size(theta));   % Flat at z0
            % Filled ellipse
            fill3(app.UIAxes3D, x, y, z_sup, 'k', 'FaceAlpha', 0.4, 'EdgeColor','k');
            fill3(app.UIAxes3D, x, y, z_inf, 'k', 'FaceAlpha', 0.4, 'EdgeColor','k');
            % 3D Visualization of ligament coordinates

            % Plot proximal points (top)
            cla(app.UIAxes3D);   % clear old plots

            hold(app.UIAxes3D, 'on');

            scatter3(app.UIAxes3D, app.ProxCoords(:,1), app.ProxCoords(:,2), app.ProxCoords(:,3), ...
                80, 'bo', 'filled', 'DisplayName', 'Proximal Points');

            scatter3(app.UIAxes3D, app.DistCoords(:,1), app.DistCoords(:,2), app.DistCoords(:,3), ...
                80, 'ro', 'filled', 'DisplayName', 'Distal Points');

            hold(app.UIAxes3D, 'off');
            % Connect proximal and distal points with lines
            for i = 1:size(app.ProxCoords,1)
                line(app.UIAxes3D,[app.ProxCoords(i,1), app.DistCoords(i,1)], ...
                     [app.ProxCoords(i,2), app.DistCoords(i,2)], ...
                     [app.ProxCoords(i,3), app.DistCoords(i,3)], ...
                     'Color', [0.5 0.5 0.5], 'LineWidth', 1);
            
            end

            xlabel(app.UIAxes3D,'X (mm)');
            ylabel(app.UIAxes3D,'Y (mm)');
            zlabel(app.UIAxes3D,'Z (mm)');
            axis(app.UIAxes3D, 'tight'); 
            title(app.UIAxes3D,'3D Visualization of Ligament Coordinates');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CoordinateSelectionTab = uitab(app.TabGroup);
            app.CoordinateSelectionTab.Title = 'Coordinate Selection';
            app.UIAxes3D = uiaxes(app.CoordinateSelectionTab);
            title(app.UIAxes3D, 'Ligament Configuration')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.CoordinateSelectionLabel = uilabel(app.CoordinateSelectionTab);
            app.CoordinateSelectionLabel.Text = 'Coordinate Selection';
            app.NumberofLigamentsEditFieldLabel = uilabel(app.CoordinateSelectionTab);
            app.NumberofLigamentsEditFieldLabel.Text = 'Number of Ligaments';
            app.NumberofLigamentsEditField = uieditfield(app.CoordinateSelectionTab, 'numeric');
            app.NumberofLigamentsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofLigamentsEditFieldValueChanged, true);
            app.AngleofLigamentsdegEditFieldLabel = uilabel(app.CoordinateSelectionTab);
            app.AngleofLigamentsdegEditFieldLabel.Text = 'Angle of Ligaments (deg)';
            app.AngleofLigamentsdegEditField = uieditfield(app.CoordinateSelectionTab, 'numeric');
            app.AngleofLigamentsdegEditField.ValueChangedFcn = createCallbackFcn(app, @AngleofLigamentsdegEditFieldValueChanged, true);
            app.OuterDiscAPLengthmmEditFieldLabel = uilabel(app.CoordinateSelectionTab);
            app.OuterDiscAPLengthmmEditFieldLabel.Text = 'Outer Disc AP Length (mm)';
            app.OuterDiscAPLengthmmEditField = uieditfield(app.CoordinateSelectionTab, 'numeric');
            app.OuterDiscAPLengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @OuterDiscAPLengthmmEditFieldValueChanged, true);
            app.InnerDiscAPlengthmmEditFieldLabel = uilabel(app.CoordinateSelectionTab);
            app.InnerDiscAPlengthmmEditFieldLabel.Text = 'Inner Disc AP length (mm)';
            app.InnerDiscAPlengthmmEditField = uieditfield(app.CoordinateSelectionTab, 'numeric');
            app.InnerDiscAPlengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @InnerDiscAPlengthmmEditFieldValueChanged, true);
            app.OuterDiscMLLengthmmEditFieldLabel = uilabel(app.CoordinateSelectionTab);
            app.OuterDiscMLLengthmmEditFieldLabel.Text = 'Outer Disc ML Length (mm)';
            app.OuterDiscMLLengthmmEditField = uieditfield(app.CoordinateSelectionTab, 'numeric');
            app.OuterDiscMLLengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @OuterDiscMLLengthmmEditFieldValueChanged, true);
            app.InnerDiscMLLengthmmEditFieldLabel = uilabel(app.CoordinateSelectionTab);
            app.InnerDiscMLLengthmmEditFieldLabel.Text = 'Inner Disc ML Length (mm)';
            app.InnerDiscMLLengthmmEditField = uieditfield(app.CoordinateSelectionTab, 'numeric');
            app.InnerDiscMLLengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @InnerDiscMLLengthmmEditFieldValueChanged, true);
            app.DiscHeightmmEditFieldLabel = uilabel(app.CoordinateSelectionTab);
            app.DiscHeightmmEditFieldLabel.Text = 'Disc Height (mm)';
            app.DiscHeightmmEditField = uieditfield(app.CoordinateSelectionTab, 'numeric');
            app.DiscHeightmmEditField.ValueChangedFcn = createCallbackFcn(app, @DiscHeightmmEditFieldValueChanged, true);
            app.PreviewButton = uibutton(app.CoordinateSelectionTab, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VL_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end